classdef StreamWare_Data_Tool_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ConverttodataarrayButton  matlab.ui.control.Button
        StreamWareGeneralExportDataConversionToolLabel  matlab.ui.control.Label
        BrowseButton              matlab.ui.control.Button
        ChosenfilesTextAreaLabel  matlab.ui.control.Label
        ChosenfilesTextArea       matlab.ui.control.TextArea
        TextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        filename; % Description
    end
    
    methods (Access = private)
        
        function s = rewrite(app)
            for i=1:size(app.filename,2)
                s(i)=string(app.filename{i});
            end
        end
        function z=get_z(app,str)
            a=str(22:end-2);
            z=0;
            n=1;
            pos=0;
            for i=1:length(a)
                if (double(a(i))~=46) b(n)=(a(i)-48); n=n+1; else pos=i; end
            end
            for i=1:length(b)
                z=z+b(i)*10^(length(b)-i);
            end
            if (pos>0) z=z/10^(length(a)-pos); end
        end
        
        
        function [V,amp,Z]=zna2019(app,nazwa)
            %% dane charakterystyczne pomiaru
            fftcalc=0;
            xt=0;
            fpc=0;
            fid=fopen(nazwa,'r');
            control=0;
            while(control~=1)
                t=fgetl(fid);
                if (length(t)>19) if (t(1:19)=='Number of positions') control=1; end; end
            end
            npos=t;
            %przeliczenie na wartości
            m=0;
            for i=1:length(npos)-21
                m=m+(double(npos(length(npos)-i+1))-48)*10^(i-1);
            end
            %%
            for j=1:m
                ctr=1;
                %[F(j),L(j),z(j)]=look_for_values();
                control=0;
                while(control~=1)
                    t=fgetl(fid);
                    if (length(t)>15) if (t(1:15)=='(mm,mm,mm,deg):') control=1; end; end
                end
                z{j}=t;%<---------
                control=0;
                while(control~=1)
                    t=fgetl(fid);
                    if (length(t)>17) if (t(1:17)=='Sample freq.,kHz:') control=1; end; end
                end
                freq=t;%<---------
                ns=fgetl(fid);%<---------
                %przeliczenie na wartości
                frequency=0;
                len=0;
                for i=1:length(freq)-20
                    frequency=frequency+(double(freq(length(freq)-i+1))-48)*10^(i-1);
                end
                for i=1:length(ns)-20
                    len=len+(double(ns(length(ns)-i+1))-48)*10^(i-1);
                end
                
                L(j)=len;
                F(j)=frequency*1e3;
                Z(j)=get_z(app,z{j});
                control=0;
                while(control~=1)
                    t=string(fgetl(fid));
                    if (t==strcat('[DATA BLOCK 1-',string(j),'-1]')) control=1; end
                end
                A(:,:,j) = fscanf(fid,'%f %f %f',[3 L(j)]);
                if (j/m*100>10*ctr) fprintf('.'); ctr=ctr+1; end
            end
            fclose('all');
            V=A;
            %assignin('base','L',L);
            %assignin('base','F',F);
            amp=nan;
        end
        function [F,L,z]=look_for_values(app)
            control=0;
            while(control~=1)
                t=fgetl(fid);
                if (length(t)>15) if (t(1:15)=='(mm,mm,mm,deg):') control=1; end; end
            end
            z=t;%<---------
            control=0;
            while(control~=1)
                t=fgetl(fid);
                if (length(t)>17) if (t(1:17)=='Sample freq.,kHz:') control=1; end; end
            end
            freq=t;%<---------
            ns=fgetl(fid);%<---------
            %przeliczenie na wartości
            frequency=0;
            len=0;
            for i=1:length(freq)-20
                frequency=frequency+(double(freq(length(freq)-i+1))-48)*10^(i-1);
            end
            for i=1:length(ns)-20
                len=len+(double(ns(length(ns)-i+1))-48)*10^(i-1);
            end
            L=len;
            F=frequency*1e3;
            
        end
        
        % if (xt==1)
        %     for i=1:F
        %     end
        %     mkdir xt;
        % pliki=fopen('ppng.txt','r');
        % for i=1:m
        % hfig=figure;
        % set(hfig, 'Visible', 'off');
        % plot(t,A(2,:,i));
        % print(hfig,'-dpng',fgetl(pliki));
        % disp(i/m*100);
        % disp('% completed');
        % close(hfig);
        % end
        % fclose(pliki);
        % disp('xt completed');
        % end
        % f=(0:L-1)/L*F;
        % assignin('base','f',f);
        % if (fftcalc==1)
        % z(m,L)=0;
        % amp(m,L)=0;
        % for j=1:m
        % z(j,:)=fft(A(2,:,j));
        % amp(j,:)=2*abs(z(j,:)/L);
        % avg(j)=mean(A(2,:,j));
        % end
        % assignin('base','amp',amp);
        % disp('fft calc completed');
        % end
        % if (fpc==1)
        %     mkdir fft;
        % pliki=fopen('pfft.txt','r');
        % for j=1:m
        %     hfig=figure;
        % set(hfig, 'Visible', 'off');
        % plot(f(2:end/2),amp(j,2:end/2));
        % print(hfig,'-dpng',fgetl(pliki));
        % disp(j/m*100);
        % disp('% completed');
        % close(hfig);
        % end
        % fclose(pliki);
        % assignin('base','amp',amp);
        % disp('fft png completed');
        % end
        
    end
    

    methods (Access = private)

        % Button pushed function: ConverttodataarrayButton
        function rad(app, event)
            clc;
            name=rewrite(app);
            assignin('base','s',name);
            fprintf('==== Start ====\n');
            s=name;
            for i=1:length(s)
                nazwa=s(i);
                [V,amp,z]=zna2019(app,nazwa);
                AMP(:,:,i)=amp;
                [h b l]=size(V);
                for j=1:l
                    for k=1:b
                        B(j,k,i)=V(2,k,j);
                    end
                end
                A{i}=B(:,:,i);
                Z{i}=z(:)';
                fprintf('%4.2f %% files completed \n',i/length(s)*100);
            end
            clearvars -except A Z s;
            assignin('base','A',A);
            assignin('base','Z',Z);
            fprintf('==== End ====\n');
        end

        % Button pushed function: BrowseButton
        function browse(app, event)
            file = uigetfile('../*.txt','MultiSelect','on');
            if(class(file)=='char')
                file2{1}=file;
                file=file2;
            end
            app.filename=file;
            app.ChosenfilesTextArea.Value=app.filename;
            %figure(app.UIFigure);
        end

        % Value changed function: ChosenfilesTextArea
        function text(app, event)
            %             value = app.ChosenfilesTextArea.Value;
            %             app.ChosenfilesTextArea.Value=app.filename;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.ConverttodataarrayButton = uibutton(app.UIFigure, 'push');
            app.ConverttodataarrayButton.ButtonPushedFcn = createCallbackFcn(app, @rad, true);
            app.ConverttodataarrayButton.Text = 'Convert to data array';
            app.StreamWareGeneralExportDataConversionToolLabel = uilabel(app.UIFigure);
            app.StreamWareGeneralExportDataConversionToolLabel.Text = 'StreamWare General Export Data Conversion Tool';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browse, true);
            app.BrowseButton.Text = 'Browse';
            app.ChosenfilesTextAreaLabel = uilabel(app.UIFigure);
            app.ChosenfilesTextAreaLabel.Text = 'Chosen files';
            app.ChosenfilesTextArea = uitextarea(app.UIFigure);
            app.ChosenfilesTextArea.ValueChangedFcn = createCallbackFcn(app, @text, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'1. Chose StreamWare General Export .txt data files using"Browse" button.'; '2. Convert data to acquire arrays:'; '- A{i}(j,k) - cell data array of k measurement points for j probe positions,'; '- Z{i}(j) - cell data array of probe positions,'; '- s(i) - names of i number of .txt files.'; ''; ''};
        end
    end

    methods (Access = public)

        % Construct app
        function app = StreamWare_Data_Tool_v2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end